classdef HybridApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hybridprocesseditorUIFigure     matlab.ui.Figure
        getreferencefromecoinventButton  matlab.ui.control.Button
        intermediateflowsLabel          matlab.ui.control.Label
        BTN_remove_inter                matlab.ui.control.Button
        elementaryflowsLabel            matlab.ui.control.Label
        TAB_inter                       matlab.ui.control.Table
        TAB_ele                         matlab.ui.control.Table
        BTN_add_inter                   matlab.ui.control.Button
        referenceproductEditFieldLabel  matlab.ui.control.Label
        E_refProd                       matlab.ui.control.EditField
        unitEditFieldLabel              matlab.ui.control.Label
        E_unit                          matlab.ui.control.EditField
        quantityexpressionEditFieldLabel  matlab.ui.control.Label
        E_quantityExp                   matlab.ui.control.EditField
        processnameLabel                matlab.ui.control.Label
        E_processName                   matlab.ui.control.EditField
        debugButton                     matlab.ui.control.Button
        calcButton                      matlab.ui.control.Button
        P_inter                         matlab.ui.container.Panel
        BTN_exchange_inter              matlab.ui.control.Button
        ProviderDropDownLabel           matlab.ui.control.Label
        DD_Provider                     matlab.ui.control.DropDown
        BTN_update_inter                matlab.ui.control.Button
        L_inter                         matlab.ui.control.Label
        quantityExpressionLabel         matlab.ui.control.Label
        E_QuantityExpression_inter      matlab.ui.control.EditField
        P_ele                           matlab.ui.container.Panel
        BTN_exchange_ele                matlab.ui.control.Button
        BTN_update_ele                  matlab.ui.control.Button
        L_ele                           matlab.ui.control.Label
        quantityexpressionLabel         matlab.ui.control.Label
        E_QuantityExpression_ele        matlab.ui.control.EditField
        BTN_remove_ele                  matlab.ui.control.Button
        BTN_add_ele                     matlab.ui.control.Button
        applyButton                     matlab.ui.control.Button
        cancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        mainApp % Description
        Lookup
        hybridPro
        
        providerTable
        
        flowRow
        eleFlowRow
        
        interSelection        
        eleSelection
        
        mode
        nextFnc
    end
    
    methods (Access = private)
        
        function showFlows(app ,process)                       
            
            h = waitbar(0,['Load Process...']);                
                        
            %fileName = app.Lookup.Filename(process.index);
            %Occ = app.T(find(contains(app.T.ActivityName,searchString)),:);
            fileName = app.Lookup.Filename((app.Lookup.ActivityName == process.name) & (app.Lookup.ReferenceProduct == process.refProduct) & (app.Lookup.Location == process.activityLoc));            
                
            tmpStruct = xml2struct("ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + fileName);                                              
            
            if isfield(tmpStruct.ecoSpold, 'activityDataset')
                ActivityStruct = tmpStruct.ecoSpold.activityDataset;
            else
                ActivityStruct = tmpStruct.ecoSpold.childActivityDataset;
            end                                          
            
            geo = ActivityStruct.activityDescription.geography.shortname.Text;
            
            app.hybridPro.name = process.name +  " (" + geo + ") - Hybrid";
            
            exchanges = ActivityStruct.flowData.intermediateExchange';
            exchangeNames = strings(length(exchanges),1);
            QuantityExpression = strings(length(exchanges),1);
            exchangeUnit = strings(length(exchanges),1);
            exchangeTypes = strings(length(exchanges),1);
            Provider = strings(length(exchanges),1);
            ProviderLoc = strings(length(exchanges),1);
            exchangeGroup = strings(length(exchanges),1);
            exchangeId = strings(length(exchanges),1);                      
            
            waitbar(0.2,h,['Load Intermediate Exchanges...']);                                   
            
            if length(exchanges) == 1
                exchangeNames = {exchanges.name.Text};
                QuantityExpression = {exchanges.Attributes.amount};
                exchangeUnit = {exchanges.unitName.Text};                
                if isfield(exchanges,'inputGroup') % input
                    exchangeGroup = ['Input ' exchanges.inputGroup.Text];
                    if exchanges.inputGroup.Text == '5'                                                                       
                        if isfield(exchanges.Attributes,'activityLinkId')
                            linkId = exchanges.Attributes.activityLinkId;
                            row = app.Lookup(contains(app.Lookup.Filename,linkId),:);
                            Provider = row.ActivityName;                           
                            ProviderLoc = row.Location;                                
                            app.hybridPro.addIntermediateFlow(exchangeNames, string(QuantityExpression), exchangeUnit, Provider, ProviderLoc);                            
                        end
                    end
                else %output
                    if exchanges.outputGroup.Text == '0'
                        exchangeTypes = 'ReferenceProduct, Output';
                        app.hybridPro.refProduct = exchangeNames;
                    end
                end                
            else
                for i=1:length(exchanges)
                    exchangeNames(i) = exchanges{i}.name.Text;
                    QuantityExpression(i) = exchanges{i}.Attributes.amount;
                    exchangeUnit(i) = exchanges{i}.unitName.Text;
                    exchangeId(i) = {exchanges{i}.Attributes.intermediateExchangeId};
                    if isfield(exchanges{i},'inputGroup') % input
                        exchangeGroup(i) = ['Input ' exchanges{i}.inputGroup.Text];
                        if exchanges{i}.inputGroup.Text == '5'
                            % get 'By-product classification'                            
                            if isfield(exchanges{i}.Attributes,'activityLinkId')
                                linkId = exchanges{i}.Attributes.activityLinkId;                                
                                row = app.Lookup(contains(app.Lookup.Filename,linkId),:);
                                Provider(i) = row.ActivityName;                                
                                ProviderLoc(i) = row.Location;                                
                                app.hybridPro.addIntermediateFlow(exchangeNames(i), string(QuantityExpression(i)), exchangeUnit(i), Provider(i), ProviderLoc(i));                            
                            end
                        end
                    else % output
                        if exchanges{i}.outputGroup.Text == '0'
                            exchangeTypes(i) = 'ReferenceProduct, Output';
                            app.hybridPro.refProduct = exchangeNames(i);
                            app.hybridPro.unit = exchangeUnit(i);
                        end
                    end
                end
            end
                                             
            FlowTable = app.hybridPro.intermediateFlowTable;           
            app.TAB_inter.Data = FlowTable;           
            
            waitbar(0.6,h,['Load Elementary Flows...']);            
            if isfield(ActivityStruct.flowData, 'elementaryExchange')
                eflows = ActivityStruct.flowData.elementaryExchange';
                eflowId = strings(length(eflows ),1);
                eflowNames = strings(length(eflows ),1);
                eQuantityExpression = strings(length(eflows ),1);
                eflowUnit = strings(length(eflows ),1);
                eflowType = strings(length(eflows ),1);
                eflowGroup = strings(length(eflows ),1);
                
                if length(eflows) > 1
                    for i = 1:length(eflows)
                        eflowId(i) = {string(eflows{i}.Attributes.elementaryExchangeId)};
                        eflowNames(i) = string(eflows{i}.name.Text);
                        eQuantityExpression(i) = string(eflows{i}.Attributes.amount);
                        eflowUnit(i) = string(eflows{i}.unitName.Text);
                        comp = eflows{i}.compartment.compartment.Text;
                        subcomp = eflows{i}.compartment.subcompartment.Text;                        
                        app.hybridPro.addElementaryFlow(eflowNames(i),string(eQuantityExpression(i)),eflowUnit(i),comp,subcomp);
                    end
                else                   
                    eflowNames = {string(eflows.name.Text)};
                    eQuantityExpression = {string(eflows.Attributes.amount)};
                    eflowUnit = {string(eflows.unitName.Text)};
                    comp = eflows.compartment.compartment.Text;
                    subcomp = eflows.compartment.subcompartment.Text;
                    app.hybridPro.addElementaryFlow(eflowNames,string(eQuantityExpression),eflowUnit,comp,subcomp);                  
                end                
            end            
            eFlowTable = app.hybridPro.elementaryFlowTable;
            app.TAB_ele.Data = eFlowTable;
            
            
            app.E_processName.Value = app.hybridPro.name;
            app.E_refProd.Value = app.hybridPro.refProduct;
            app.E_unit.Value = app.hybridPro.unit;
            
            app.E_quantityExp.Value = 'mass';            
            
            close(h)                                   
        end
        
        function activateInterEdits(app)           
            app.BTN_exchange_inter.Enable = true;            
            app.E_QuantityExpression_inter.Enable = true;
            app.DD_Provider.Enable = true;
            app.BTN_update_inter.Enable = true;
        end
        
        function deactivateInterEdits(app)          
            app.BTN_exchange_inter.Enable = false;            
            app.E_QuantityExpression_inter.Enable = false;
            app.DD_Provider.Enable = false;
            app.BTN_update_inter.Enable = false;
            app.L_inter.Text = '';
        end
        
        function activateEleEdits(app)
            app.BTN_exchange_ele.Enable = true;
            app.E_QuantityExpression_ele.Enable = true;            
            app.BTN_update_ele.Enable = true;
        end
        
        function deactivateEleEdits(app)
            app.BTN_exchange_ele.Enable = false;
            app.E_QuantityExpression_ele.Enable = false;
            app.BTN_update_ele.Enable = false;
            app.L_ele.Text = '';
        end
        
        function setInterEdits(app,index)
            app.activateInterEdits;
            row = app.hybridPro.intermediateFlowTable(index,:);
            app.L_inter.Text = row.FlowName;
            app.E_QuantityExpression_inter.Value = row.QuantityExpression;
            refFlow = row.FlowName;
            providerList = app.getProvider(refFlow);
            app.DD_Provider.Items = providerList;
            app.DD_Provider.Value = row.Provider + ' (' + row.ProviderLoc + ')';
        end
        
        function setEleEdits(app,index)            
            app.L_ele.Text = app.eleFlowRow.FlowName;
            app.E_QuantityExpression_ele.Value = app.eleFlowRow.QuantityExpression;
        end
        
        
        function providerList = getProvider(app,refFlow)
            app.providerTable = table();
            app.providerTable = app.mainApp.ie((string(app.mainApp.ie.product) == string(refFlow)),:);            
            providerList = string(app.providerTable.activityName) + ' (' + string(app.providerTable.geography) + ')';            
        end
        
        function replaceInterFlow(app,flowRow)            
            app.L_inter.Text = flowRow.activityName;
            providerList = app.getProvider(string(flowRow.product));
            app.DD_Provider.Items = providerList;
            app.DD_Provider.Value = string(flowRow.activityName) + ' (' + string(flowRow.geography) + ')';
            FlowName = flowRow.product;
            QuantityExpression = app.E_QuantityExpression_inter.Value;
            Unit = flowRow.unitName;
            Provider = flowRow.activityName;
            ProviderLoc = flowRow.geography;
            app.flowRow = table(FlowName, QuantityExpression, Unit, Provider, ProviderLoc);            
        end
        
        function replaceEleFlow(app,flowRow)
            app.L_ele.Text = flowRow.name;
            FlowName = flowRow.name;
            QuantityExpression = app.E_QuantityExpression_ele.Value;
            Unit = flowRow.unitName;
            Compartment = flowRow.compartment;
            Subcompartment = flowRow.subcompartment;
            app.eleFlowRow = table(FlowName, QuantityExpression, Unit, Compartment, Subcompartment);
        end
        
        function addInterFlow(app,flowRow)
            FlowName = flowRow.product;
            Unit = flowRow.unitName;
            QuantityExpression = 1; 
            Provider = flowRow.activityName;
            ProviderLoc = flowRow.geography;            
            app.hybridPro.addIntermediateFlow(FlowName, QuantityExpression, Unit, Provider, ProviderLoc)
            app.TAB_inter.Data = app.hybridPro.intermediateFlowTable;
        end
        
        function addEleFlow(app,flowRow)
            FlowName = flowRow.name;
            QuantityExpression = 1;
            Unit = flowRow.unitName;
            Compartment = flowRow.compartment;
            Subcompartment = flowRow.subcompartment;
            app.hybridPro.addElementaryFlow(FlowName, QuantityExpression, Unit, Compartment, Subcompartment);
            app.TAB_ele.Data = app.hybridPro.elementaryFlowTable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, hprocess, nextFnc)
            app.deactivateInterEdits();
            app.deactivateEleEdits();
            app.mainApp = mainApp;
            app.nextFnc = nextFnc;            
            app.Lookup = readtable('ecoinvent 3.6_cut-off_ecoSpold02\FilenameToActivtiyLookup.csv','Format','%s %s %s %s');
            
            app.TAB_inter.ColumnName = [{'flow name'}, {'amount'}, {'unit'}, {'provider name'}, {'provider reference'},{'provider location'}];                                                                      
            app.TAB_ele.ColumnName = [{'flow name'}, {'amount'}, {'unit'}, {'compartment'},{'subcompartment'}];
            
            if ~isempty(hprocess)
                app.hybridPro = hprocess.copy;                                               
            else
                app.hybridPro = HybridProcess("new hybrid process");
            end
                                   
            app.TAB_inter.Data = app.hybridPro.intermediateFlowTable;
            app.TAB_ele.Data = app.hybridPro.elementaryFlowTable;
            app.E_processName.Value = app.hybridPro.name;
            app.E_quantityExp.Value = app.hybridPro.quantityExpression;
            app.E_refProd.Value = app.hybridPro.refProduct;             
            app.E_unit.Value = app.hybridPro.unit;
            
        end

        % Button pushed function: getreferencefromecoinventButton
        function getreferencefromecoinventButtonPushed(app, event)
            ProcessEditor(app.mainApp,"get",@app.showFlows);            
        end

        % Button pushed function: debugButton
        function debugButtonPushed(app, event)
            assignin("base","happ",app);
        end

        % Button pushed function: calcButton
        function calcButtonPushed(app, event)
            app.hybridPro.calculateLCIA(app.mainApp);
        end

        % Cell selection callback: TAB_inter
        function TAB_interCellSelection(app, event)
            selectedCell = event.Indices;            
            index = event.Indices(1);
            app.interSelection = index;            
            if ~isempty(selectedCell)                
                app.setInterEdits(index);
                app.flowRow = app.hybridPro.intermediateFlowTable(index,:);
            else
                app.deactivateInterEdits();
            end
            
        end

        % Button pushed function: BTN_exchange_inter
        function BTN_exchange_interButtonPushed(app, event)
            EcoSearchApp(app.mainApp,@app.replaceInterFlow,'intermediate');
        end

        % Button pushed function: BTN_update_inter
        function BTN_update_interButtonPushed(app, event)
            app.flowRow.QuantityExpression = app.E_QuantityExpression_inter.Value;                       
            app.flowRow.Provider = app.providerTable.activityName(string(app.DD_Provider.Items) == string(app.DD_Provider.Value));
            app.flowRow.ProviderLoc = app.providerTable.geography(string(app.DD_Provider.Items) == string(app.DD_Provider.Value));
            app.hybridPro.intermediateFlowTable(app.interSelection,:) = app.flowRow;            
            app.TAB_inter.Data = app.hybridPro.intermediateFlowTable;
        end

        % Button pushed function: BTN_remove_inter
        function BTN_remove_interButtonPushed(app, event)
            app.hybridPro.intermediateFlowTable(app.interSelection,:) = [];
            app.TAB_inter.Data = app.hybridPro.intermediateFlowTable;
        end

        % Button pushed function: BTN_add_inter
        function BTN_add_interButtonPushed(app, event)
            EcoSearchApp(app.mainApp,@app.addInterFlow,'intermediate');
        end

        % Cell selection callback: TAB_ele
        function TAB_eleCellSelection(app, event)
            selectedCell = event.Indices;            
            index = event.Indices(1);
            app.eleSelection = index;                       
            if ~isempty(selectedCell)
                app.activateEleEdits();
                app.eleFlowRow = app.hybridPro.elementaryFlowTable(index,:);                                
                app.setEleEdits(index);                
            else
                app.deactivateEleEdits();
            end
            
        end

        % Button pushed function: BTN_exchange_ele
        function BTN_exchange_eleButtonPushed(app, event)
            EcoSearchApp(app.mainApp,@app.replaceEleFlow,'elementary');
        end

        % Button pushed function: BTN_update_ele
        function BTN_update_eleButtonPushed(app, event)
            app.eleFlowRow.QuantityExpression = app.E_QuantityExpression_ele.Value;
            app.hybridPro.elementaryFlowTable(app.eleSelection,:) = app.eleFlowRow;
            app.TAB_ele.Data = app.hybridPro.elementaryFlowTable;
        end

        % Button pushed function: BTN_remove_ele
        function BTN_remove_eleButtonPushed(app, event)
            app.hybridPro.elementaryFlowTable(app.eleSelection,:) = [];
            app.TAB_ele.Data = app.hybridPro.elementaryFlowTable;
        end

        % Button pushed function: BTN_add_ele
        function BTN_add_eleButtonPushed(app, event)
            EcoSearchApp(app.mainApp,@app.addEleFlow,'elementary');
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            app.hybridPro.name = app.E_processName.Value;
            app.hybridPro.refProduct = app.E_refProd.Value;
            app.hybridPro.quantityExpression = app.E_quantityExp.Value;
            app.hybridPro.unit = app.E_unit.Value;
            app.nextFnc(app.hybridPro)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hybridprocesseditorUIFigure = uifigure('Visible', 'off');
            app.hybridprocesseditorUIFigure.Name = 'hybrid process editor';
            app.getreferencefromecoinventButton = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.getreferencefromecoinventButton.ButtonPushedFcn = createCallbackFcn(app, @getreferencefromecoinventButtonPushed, true);
            app.getreferencefromecoinventButton.Text = {'get reference '; 'from ecoinvent'};
            app.intermediateflowsLabel = uilabel(app.hybridprocesseditorUIFigure);
            app.intermediateflowsLabel.Text = 'intermediate flows';
            app.BTN_remove_inter = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.BTN_remove_inter.ButtonPushedFcn = createCallbackFcn(app, @BTN_remove_interButtonPushed, true);
            app.BTN_remove_inter.Text = 'remove';
            app.elementaryflowsLabel = uilabel(app.hybridprocesseditorUIFigure);
            app.elementaryflowsLabel.Text = 'elementary flows';
            app.TAB_inter = uitable(app.hybridprocesseditorUIFigure);
            app.TAB_inter.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TAB_inter.RowName = {};
            app.TAB_inter.CellSelectionCallback = createCallbackFcn(app, @TAB_interCellSelection, true);
            app.TAB_ele = uitable(app.hybridprocesseditorUIFigure);
            app.TAB_ele.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TAB_ele.RowName = {};
            app.TAB_ele.CellSelectionCallback = createCallbackFcn(app, @TAB_eleCellSelection, true);
            app.BTN_add_inter = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.BTN_add_inter.ButtonPushedFcn = createCallbackFcn(app, @BTN_add_interButtonPushed, true);
            app.BTN_add_inter.Text = 'add new';
            app.referenceproductEditFieldLabel = uilabel(app.hybridprocesseditorUIFigure);
            app.referenceproductEditFieldLabel.Text = 'reference product';
            app.E_refProd = uieditfield(app.hybridprocesseditorUIFigure, 'text');
            app.unitEditFieldLabel = uilabel(app.hybridprocesseditorUIFigure);
            app.unitEditFieldLabel.Text = 'unit';
            app.E_unit = uieditfield(app.hybridprocesseditorUIFigure, 'text');
            app.quantityexpressionEditFieldLabel = uilabel(app.hybridprocesseditorUIFigure);
            app.quantityexpressionEditFieldLabel.Text = 'quantity expression';
            app.E_quantityExp = uieditfield(app.hybridprocesseditorUIFigure, 'text');
            app.processnameLabel = uilabel(app.hybridprocesseditorUIFigure);
            app.processnameLabel.Text = 'process name';
            app.E_processName = uieditfield(app.hybridprocesseditorUIFigure, 'text');
            app.debugButton = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.debugButton.ButtonPushedFcn = createCallbackFcn(app, @debugButtonPushed, true);
            app.debugButton.Text = 'debug';
            app.calcButton = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.calcButton.ButtonPushedFcn = createCallbackFcn(app, @calcButtonPushed, true);
            app.calcButton.Text = 'calc';
            app.P_inter = uipanel(app.hybridprocesseditorUIFigure);
            app.P_inter.Title = 'edit intermediate flow';
            app.BTN_exchange_inter = uibutton(app.P_inter, 'push');
            app.BTN_exchange_inter.ButtonPushedFcn = createCallbackFcn(app, @BTN_exchange_interButtonPushed, true);
            app.BTN_exchange_inter.Text = 'exchange flow';
            app.ProviderDropDownLabel = uilabel(app.P_inter);
            app.ProviderDropDownLabel.Text = 'Provider';
            app.DD_Provider = uidropdown(app.P_inter);
            app.BTN_update_inter = uibutton(app.P_inter, 'push');
            app.BTN_update_inter.ButtonPushedFcn = createCallbackFcn(app, @BTN_update_interButtonPushed, true);
            app.BTN_update_inter.Text = 'apply';
            app.L_inter = uilabel(app.P_inter);
            app.quantityExpressionLabel = uilabel(app.P_inter);
            app.quantityExpressionLabel.Text = 'quantity Expression';
            app.E_QuantityExpression_inter = uieditfield(app.P_inter, 'text');
            app.P_ele = uipanel(app.hybridprocesseditorUIFigure);
            app.P_ele.Title = 'edit intermediate flow';
            app.BTN_exchange_ele = uibutton(app.P_ele, 'push');
            app.BTN_exchange_ele.ButtonPushedFcn = createCallbackFcn(app, @BTN_exchange_eleButtonPushed, true);
            app.BTN_exchange_ele.Text = 'exchange flow';
            app.BTN_update_ele = uibutton(app.P_ele, 'push');
            app.BTN_update_ele.ButtonPushedFcn = createCallbackFcn(app, @BTN_update_eleButtonPushed, true);
            app.BTN_update_ele.Text = 'apply';
            app.L_ele = uilabel(app.P_ele);
            app.L_ele.Text = 'Label2';
            app.quantityexpressionLabel = uilabel(app.P_ele);
            app.quantityexpressionLabel.Text = 'quantity expression';
            app.E_QuantityExpression_ele = uieditfield(app.P_ele, 'text');
            app.BTN_remove_ele = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.BTN_remove_ele.ButtonPushedFcn = createCallbackFcn(app, @BTN_remove_eleButtonPushed, true);
            app.BTN_remove_ele.Text = 'remove';
            app.BTN_add_ele = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.BTN_add_ele.ButtonPushedFcn = createCallbackFcn(app, @BTN_add_eleButtonPushed, true);
            app.BTN_add_ele.Text = 'add new';
            app.applyButton = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.cancelButton = uibutton(app.hybridprocesseditorUIFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'cancel';
            % Show the figure after all components are created
            app.hybridprocesseditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HybridApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hybridprocesseditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hybridprocesseditorUIFigure)
        end
    end
end