classdef LCD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LifeCycleDesignerUIFigure  matlab.ui.Figure
        fileMenu                   matlab.ui.container.Menu
        newMenu                    matlab.ui.container.Menu
        openMenu                   matlab.ui.container.Menu
        importMenu                 matlab.ui.container.Menu
        saveMenu                   matlab.ui.container.Menu
        mappingMenu                matlab.ui.container.Menu
        mappingeditorMenu          matlab.ui.container.Menu
        settingsMenu               matlab.ui.container.Menu
        indicatormethodMenu        matlab.ui.container.Menu
        indicatorcategoryMenu      matlab.ui.container.Menu
        lifelinkdirectoryMenu      matlab.ui.container.Menu
        loadMatrixMenu             matlab.ui.container.Menu
        saveengineMenu             matlab.ui.container.Menu
        loadengineMenu             matlab.ui.container.Menu
        debugMenu                  matlab.ui.container.Menu
        exportappMenu              matlab.ui.container.Menu
        clearprocessengineMenu     matlab.ui.container.Menu
        Btn_LifeLink               matlab.ui.control.StateButton
        CB_ShowFeatures            matlab.ui.control.CheckBox
        TV_Components              matlab.ui.container.Tree
        P_Component                matlab.ui.container.Panel
        materialDropDownLabel      matlab.ui.control.Label
        DD_Material                matlab.ui.control.DropDown
        classificationLabel        matlab.ui.control.Label
        DD_Classification          matlab.ui.control.DropDown
        BTN_updateComponent        matlab.ui.control.Button
        CB_IgnoreComponent         matlab.ui.control.CheckBox
        masskgEditFieldLabel       matlab.ui.control.Label
        E_Mass                     matlab.ui.control.NumericEditField
        volumemLabel               matlab.ui.control.Label
        E_Volume                   matlab.ui.control.NumericEditField
        analysisPanel              matlab.ui.container.Panel
        L_Indicator                matlab.ui.control.Label
        productsystemLabel         matlab.ui.control.Label
        BTN_Loops                  matlab.ui.control.Button
        methodDropDownLabel        matlab.ui.control.Label
        DD_Method                  matlab.ui.control.DropDown
        indicatorDropDownLabel     matlab.ui.control.Label
        DD_Indicator               matlab.ui.control.DropDown
        categoryLabel              matlab.ui.control.Label
        DD_Category                matlab.ui.control.DropDown
        BTN_SensitivityAnalysis    matlab.ui.control.Button
        BTN_Reference              matlab.ui.control.Button
        BTN_Compare                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        P_Settings                 matlab.ui.container.Panel
        BTN_Open                   matlab.ui.control.Button
        BTN_Edit                   matlab.ui.control.Button
        BTN_Add                    matlab.ui.control.Button
        BTN_Close                  matlab.ui.control.Button
        E_Quantity                 matlab.ui.control.NumericEditField
        L_functionalunit           matlab.ui.control.Label
        L_availableProcesses       matlab.ui.control.Label
        DD_alternativeProcesses    matlab.ui.control.DropDown
        BTN_updateProcess          matlab.ui.control.Button
        E_Correction               matlab.ui.control.NumericEditField
        L_Correction               matlab.ui.control.Label
        L_availableLocations       matlab.ui.control.Label
        DD_availableLocations      matlab.ui.control.DropDown
        L_Stage                    matlab.ui.control.Label
        CB_ShowImportedStructure   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        mappingApp %Mapping UI
        processesApp %ProcessEditor
        
        fileName = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml'; %Xml Dir        
        I_asm %Import Model Structure        
                       
    end
    
    properties (Access = public)               
        activeElement %selected component in TreeView
        stageId = 0 %selected stage        
        operationId = 0 %selected operation
        processId = 0; %selected process        
        classificationType %used for classification updates, set when clicking on stages
        classificationTypes = ["production","assembly","use","eol"] %lists all possible classificationTypes        
        asmStageId = 0 %selected stage in assembly mode
        
        model = LCModel('mainModel');
        modelImpact
        
        referenceModel
        
        mode = "LCModel" %can be 'Assembly' 'StageResults' 'LCModel' 'Stage' 'Process' 'Sensitivity'
        A_inv
        A_min
        B
        LCIA
        Q        
        C
        ie
        processEngine ProcessEngine %handles creation and use of processess        
        
        %configList
        
        configTable
        altProcessesTable
        materialList        
        classificationList               
        
        lciaMethod string
        lciaIndicator string
        lciaIndex double % identifies impact for selected indicator
        lciaUnit string

    end
    
    methods (Access = public)       
        
        function [coordinateSelected, minIdx] = getCoord(app, hObj, event)                                    
                x = hObj.XData; 
                y = hObj.YData; 
                z = hObj.ZData; 
                pt = event.IntersectionPoint;       
                coordinates = [x(:),y(:),z(:)];     
                dist = pdist2(pt,coordinates);      
                [~, minIdx] = min(dist);            
                coordinateSelected = coordinates(minIdx,:);                    
                persistent chk;
                if isempty(chk)
                      chk = 1;
                      pause(0.2); %Add a delay to distinguish single click from a double click
                      if chk == 1
                          %single click
                          hf = get(hObj,'parent');
                          n = get(hf,'parent');
                          b = get(n,'selectiontype');
                          if strcmpi(b,'normal')
                            app.SetOptions(minIdx,hObj);                        
                          else
                          disp('rightclick')
                          end
                          
                          chk = [];
                      end
                else
                      chk = [];
                      %double click
                      app.SetOptions(minIdx,hObj);                                                                                     
                      app.openElement();
                end                
        end
        
        function SetOptions(app,id,hObj)                
            
            if (app.mode =="LCModel")
                if app.stageId > 0 %reset previous selection
                    highlight(hObj,app.stageId,'MarkerSize',30);                    
                end
                highlight(hObj,id,'MarkerSize',40);
                stageName = app.activeElement.stages(id).name;
                app.P_Settings.Title = "settings - " + stageName;
                app.stageId = id; 
                app.setClassificationType(stageName)
                app.BTN_updateProcess.Visible = false;                      
                
             elseif (app.mode == "Stage")
                if app.operationId > 0 %reset previous selection
                    highlight(hObj,app.operationId,'MarkerSize',30);
                end
                highlight(hObj,id,'MarkerSize',40);
                app.operationId = id; 
                %app.P_Settings.Title = app.activeElement.stages(app.stageId).processes(id).activityType;
                app.P_Settings.Title = "settings - " + app.activeElement.stages(app.stageId).operations(id).name;                
            elseif (app.mode == "Operation")
                if app.processId > 0 %reset previous selection
                    highlight(hObj,app.processId,'MarkerSize',30);
                end
                process = app.activeElement.stages(app.stageId).operations(app.operationId).processes(id);                
                highlight(hObj,id,'MarkerSize',40);
                app.P_Settings.Title = "settings - " + process.activityName;                
                app.L_functionalunit.Text = string("functional unit: " + string(process.functionalUnit) + " [" + string(process.unit) + "]");
                app.enableProcessEdits();
                app.E_Quantity.Value = process.quantity;               
                app.E_Correction.Value = process.correction;
                app.DD_availableLocations.Items = process.locList;
                app.DD_availableLocations.Value = process.activityLoc;
                app.processId = id; 
                for a = 1:length(process.alternativeProcesses)
                    alternativesCell(a) = process.alternativeProcesses(a).activityName;
                end                
                app.DD_alternativeProcesses.Items = sort(alternativesCell);
                app.DD_alternativeProcesses.Value = process.activityName;
                
            elseif (app.mode == "Process")
                %nothing yet
            elseif (app.mode == "Assembly") 
                if app.asmStageId > 0 %reset previous selection
                    highlight(hObj,app.asmStageId,'MarkerSize',30);                    
                end
                highlight(hObj,id,'MarkerSize',40);
                app.asmStageId = id;                                
                app.BTN_Open.Enable = true;                                                
            end             
        end
        
    end
    
    methods (Access = private)
        
        function setClassificationType(app,stageName)
            if ~isempty(stageName)
                switch stageName
                    case "material" 
                        app.classificationType = "production";
                    case "production"
                        app.classificationType = "production";
                    case "assembly"
                        app.classificationType = "assembly";
                    case "distribution"
                        app.classificationType = "assembly";
                    case "use"
                        app.classificationType = "use";
                    case "disposal"
                        app.classificationType = "eol";
                    case "maintenance"
                        app.classificationType = "eol";
                    case "refurbishment"
                        app.classificationType = "eol";
                    case "remanufacturing"
                        app.classificationType = "eol";
                    case "recycling"
                        app.classificationType = "eol";
                end            
                possibleClassifications = app.classificationList.(app.classificationType){app.materialList == app.activeElement.material};
                app.DD_Classification.Items = possibleClassifications;   
                app.DD_Classification.Value = string(app.activeElement.classification.(app.classificationType));                
                app.DD_Classification.Enable = true;
            else
                app.DD_Classification.Items = cell(0);
                app.DD_Classification.Enable = false;
            end  
        end
        
        function openElement(app)
             if app.mode == "LCModel" && app.stageId > 0
                app.activeElement.stages(app.stageId).displayStage(app,'impact');
                app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name;
                app.mode = "Stage";
                app.BTN_Open.Text = "open operation";
                app.BTN_Close.Text = "close stage";    
                app.BTN_Close.Enable = true;
                app.operationId = 0;                
                app.E_Quantity.Visible = false;                
                app.L_functionalunit.Visible = false;
            elseif app.mode == "Stage" && app.operationId > 0                    
                app.BTN_Open.Text = "open process";    
                app.BTN_Close.Text = "close operation";    
                op = app.activeElement.stages(app.stageId).operations(app.operationId);
                op.displayOperation(app,'impact');
                app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name + ' / ' + op.name;
                app.mode = "Operation";
                app.processId = 0;                       
                app.P_Settings.Title = "settings";
                app.showProcessEdits();
             elseif app.mode == "Operation" && app.processId > 0
                app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId).displayProcess(app);
                app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name + ' / ' + app.activeElement.stages(app.stageId).operations(app.operationId).name + ' / ' + app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId).activityName;
                app.mode = "Process";
                app.BTN_Open.Text = "open";
                app.BTN_Open.Enable = false;
                app.BTN_Close.Text = "close process";                                                    
            elseif app.mode == "Process"
                % nothing
             elseif app.mode == "Assembly" && app.asmStageId > 0
                 app.activeElement.showStageResults(app,app.asmStageId);    
                 app.BTN_Open.Text = "open";
                 app.BTN_Open.Enable = false;
                 app.BTN_Close.Text = "close stage impacts";   
                 app.BTN_Close.Enable = true;
                 app.mode = "StageResults"; 
                 app.L_Stage.Text = app.activeElement.name + " (" + string(app.activeElement.generateLCIA(app)) +" "+ app.lciaUnit + ")";
             end
        end
        
        function closeElement(app)
                if app.mode == "Stage" || app.mode == "Sensitivity"
                    app.BTN_Open.Text = "open stage";    
                    app.BTN_Close.Text = "close";    
                    app.BTN_Close.Enable = false;
                    app.activeElement.displayComponent(app);
                    app.L_Stage.Text = app.activeElement.name + " (" + string(app.activeElement.generateLCIA(app)) +" "+ app.lciaUnit + ")";
                    app.mode = "LCModel";                    
                    app.E_Quantity.Visible = false;                    
                    app.L_functionalunit.Visible = false;
                    app.P_Settings.Title = "settings";
                elseif app.mode == "Operation"
                    app.BTN_Open.Text = "open operation";    
                    app.BTN_Close.Text = "close stage";    
                    app.activeElement.stages(app.stageId).displayStage(app,'impact');
                    app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name;
                    app.mode = "Stage";                       
                    app.P_Settings.Title = "settings";      
                    app.hideProcessEdits();
                elseif app.mode == "Process"
                    app.BTN_Edit.Visible = false;
                    app.BTN_Add.Visible = false;
                    app.BTN_Open.Enable = true;
                    app.BTN_Open.Text = "open process";    
                    app.BTN_Close.Text = "close operation";    
                    app.activeElement.stages(app.stageId).operations(app.operationId).displayOperation(app,'impact');
                    app.L_Stage.Text = app.activeElement.name + ' / ' + app.activeElement.stages(app.stageId).name + ' / ' + app.activeElement.stages(app.stageId).operations(app.operationId).name;
                    app.mode = "Operation";   
                    app.E_Quantity.Enable = false;                    
                elseif app.mode =="StageResults"
                    app.activeElement.displayAssembly(app);                    
                    app.mode = "Assembly";
                    app.asmStageId = 0;              
                    app.BTN_Close.Text = "close";
                    app.BTN_Close.Enable = false;
                    app.BTN_Open.Text = "open stage impacts";
                    app.BTN_Open.Enable = true;                
                elseif app.mode == "ProcessOpen"
                    app.mode = "Process";
                end                
        end
        
        function loadMatrix(app)
            h = waitbar(0.2,['import matrices...']);
            load('LciMat'); 
            waitbar(0.5,h,['load matrices...']);
            app.A_inv = A_inv;
            app.A_min= A_min;
            app.B = B;
            app.LCIA = LCIA;
            app.Q = Q;
            app.C = C;
            waitbar(0.8,h,['load matrices...']);            
            app.ie = ie;
            close(h)
            load('materials.mat');
            app.materialList = materialList;            
            for c = 1:length(app.classificationTypes)
                load(app.classificationTypes(c)+'ClassificationList.mat') %includes material, production
                app.classificationList.(app.classificationTypes(c))= classifications;
            end
            %load("ConfigList.mat",'configList');
            %app.configList = configList;
            in = load('configTable.mat');
            app.configTable = in.configTable;            
            app.altProcessesTable = in.altProcessesTable;
            app.processEngine = app.processEngine.loadProcesses();
            
            
        end
        
        function listIndicators(app)
            lciaTableMethods = app.LCIA(app.LCIA.method == app.lciaMethod,:);            
            indicatorTableMethods = lciaTableMethods(lciaTableMethods.category == string(app.DD_Category.Value),:);
            indicators = unique(indicatorTableMethods.indicator);
            app.DD_Indicator.Items = indicators;
            app.updateLCIA();
        end
        
        function listCategories(app)            
            lciaTableMethods = app.LCIA(app.LCIA.method == app.lciaMethod,:);            
            categories = unique(lciaTableMethods.category);
            app.DD_Category.Items = categories;
            app.listIndicators();            
        end
        
        function updateLCIA(app)
            app.lciaIndicator = app.DD_Indicator.Value;                  
            lciaTableMethods = app.LCIA(app.LCIA.method == app.lciaMethod,:);            
            categoryTableMethods = lciaTableMethods(lciaTableMethods.category == string(app.DD_Category.Value),:);
            indexTable = categoryTableMethods(categoryTableMethods.indicator == app.lciaIndicator,:);            
            app.lciaIndex = indexTable.index;
            app.lciaUnit = indexTable.unitName;
            if app.CB_ShowImportedStructure.Value
                app.modelImpact = app.model.assemblies.generateLCIA(app);                
                switch app.mode
                    case "Assembly"
                        app.activeElement.displayAssembly(app);                        
                    case "LCModel"
                        app.activeElement.displayComponent(app);                        
                    case "Stage"
                        app.activeElement.stages(app.stageId).displayStage(app,'impact');
                    case "Operation"
                        app.activeElement.stages(app.stageId).operations(app.operationId).displayOperation(app,'impact');
                    case "Process"
                        app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId).displayProcess(app);
                    case "StageResults"
                        app.activeElement.showStageResults(app,app.asmStageId);                
                end
            end           
        end
        
        function showProcessEdits(app)
            app.L_Correction.Visible = true;
            app.E_Correction.Visible = true;            
            app.E_Correction.Value = 0;
            app.E_Correction.Enable = false;
            app.L_availableLocations.Visible = true;
            app.DD_availableLocations.Visible = true;                        
            app.DD_availableLocations.Items = {};
            app.DD_availableLocations.Enable = false;
            app.E_Quantity.Enable = false;
            app.E_Quantity.Visible = true;
            app.E_Quantity.Value = 0;            
            app.L_functionalunit.Visible = true;                                
            app.L_functionalunit.Text = "functional unit";                        
            app.L_availableProcesses.Visible = true;
            app.DD_alternativeProcesses.Visible = true;                       
            app.DD_alternativeProcesses.Items = {};                           
            app.DD_alternativeProcesses.Enable = false;
            app.BTN_updateProcess.Visible = true;
            app.BTN_updateProcess.Enable = false;
            app.BTN_Edit.Visible = true;
            app.BTN_Edit.Enable = false;
            app.BTN_Add.Visible = true;
            app.BTN_Add.Enable = false;
        end
        
        function hideProcessEdits(app)
            app.L_Correction.Visible = false;
            app.E_Correction.Visible = false;                        
            app.L_availableLocations.Visible = false;
            app.DD_availableLocations.Visible = false;                                                
            app.E_Quantity.Visible = false;                 
            app.L_functionalunit.Visible = false;                                            
            app.L_availableProcesses.Visible = false;
            app.DD_alternativeProcesses.Visible = false;                                   
            app.BTN_updateProcess.Visible = false;       
            app.BTN_Edit.Visible = false;
            app.BTN_Add.Visible = false;
        end
        
        function enableProcessEdits(app)
            app.E_Correction.Enable = true;
            app.DD_availableLocations.Enable = true;
            app.E_Quantity.Enable = true;
            app.DD_alternativeProcesses.Enable = true;
            %app.BTN_Edit.Enable = true;
            %app.BTN_Add.Enable = true;
            app.BTN_updateProcess.Enable = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %profile on
            app.hideProcessEdits();
            importMenuSelected(app);            
            app.processEngine = ProcessEngine;
            app.loadengineMenuSelected(app);
            app.loadMatrix();            
            methods = unique(app.LCIA.method);
            app.DD_Method.Items = methods;
            app.DD_Method.Value = 'ReCiPe Midpoint (I) V1.13';     
            app.lciaMethod = 'ReCiPe Midpoint (I) V1.13';
            app.listCategories(); 
            app.DD_Category.Value = 'climate change';
            app.listIndicators();
            app.DD_Indicator.Value = 'GWP20';
            app.lciaIndex = 816;            
            %profile viewer
        end

        % Menu selected function: mappingeditorMenu
        function mappingeditorMenuSelected(app, event)
            app.mappingApp = Mapping;
        end

        % Menu selected function: importMenu
        function importMenuSelected(app, event)
            
            c=xml2struct(app.fileName);                                     
            app.I_asm = struct2I_Assembly(c);            
            app.TV_Components.Children.delete;
            value = app.CB_ShowFeatures.Value;
            DisplayI_Assembly(app.TV_Components,app.I_asm,value);
            expand(app.TV_Components,'all');              
            
            
        end

        % Value changed function: CB_ShowFeatures
        function CB_ShowFeaturesValueChanged(app, event)
            value = app.CB_ShowFeatures.Value;
            app.TV_Components.Children.delete;
            DisplayI_Assembly(app.TV_Components,app.I_asm,value)
            expand(app.TV_Components,'all');                                
            
        end

        % Selection changed function: TV_Components
        function TV_ComponentsSelectionChanged(app, event)
            if app.CB_ShowImportedStructure.Value
                selectedNodes = app.TV_Components.SelectedNodes;                                   
                app.activeElement = app.model.assemblies.getElementById(selectedNodes.NodeData.id);
                app.mode = 'LCModel';                
                app.P_Settings.Title = "settings";
                app.hideProcessEdits();                
                app.BTN_Open.Text = "open stage";    
                app.BTN_Close.Text = "close";    
                app.BTN_Close.Enable = false;
                
                if isa(app.activeElement, "Component")
                    app.activeElement.displayComponent(app);
                    app.setClassificationType([]);
                    app.BTN_SensitivityAnalysis.Enable = true;
                    app.BTN_Loops.Enable = true;
                end
                if isa(app.activeElement, "Assembly")                    
                    app.activeElement.displayAssembly(app);                    
                    app.mode = "Assembly";
                    app.asmStageId = 0;
                    app.BTN_Open.Text = "open stage impacts";    
                    app.BTN_SensitivityAnalysis.Enable = false;
                    app.BTN_Loops.Enable = false;
                end                               
                
                if isprop(app.activeElement,'name')
                    app.P_Component.Title = app.activeElement.name;                    
                    app.L_Stage.Text = app.activeElement.name + " (" + string(app.activeElement.generateLCIA(app)) +" "+ app.lciaUnit + ")";
                else
                    app.P_Component.Title = '';
                    app.L_Stage.Text = '';
                end            
                if isprop(app.activeElement,'material')
                    app.DD_Material.Value = string(app.activeElement.material);                
                    app.DD_Material.Enable = true;
                else
                    app.DD_Material.Items = cell(0);
                    app.DD_Material.Enable = false;
                end                                          
            end
        end

        % Button pushed function: BTN_Open
        function BTN_OpenButtonPushed(app, event)
            app.openElement();
        end

        % Value changed function: CB_ShowImportedStructure
        function CB_ShowImportedStructureValueChanged(app, event)
            value = app.CB_ShowImportedStructure.Value;            
            if value
                app.CB_ShowFeatures.Enable = false;
                app.TV_Components.Children.delete;
                app.model.assemblies = I_Assembly2Assembly(app,app.I_asm);
                %profile on
                h = waitbar(0.2,['classify assembly...']);                
                app.model.assemblies = app.model.assemblies.classify(app);           
                close(h);
                %profile viewer
                app.modelImpact = app.model.assemblies.generateLCIA(app);
                app.model.assemblies.displayAssemblyTree(app.TV_Components)
                expand(app.TV_Components,'all');                 
                app.model.assemblies.displayAssembly(app);
            else                            
                app.CB_ShowFeaturesValueChanged();                               
                app.CB_ShowFeatures.Enable = true;                
            end
        end

        % Menu selected function: loadMatrixMenu
        function loadMatrixMenuSelected(app, event)
            app.loadMatrix();
        end

        % Menu selected function: saveengineMenu
        function saveengineMenuSelected(app, event)
            app.processEngine.saveProcesses;
        end

        % Menu selected function: loadengineMenu
        function loadengineMenuSelected(app, event)
           app.processEngine = app.processEngine.loadProcesses;
        end

        % Menu selected function: exportappMenu
        function exportappMenuSelected(app, event)
            lcm = app.model;
            h = waitbar(0.2,['exporting model ...']);            
            save('debugLC','lcm','-v7.3');
            close(h); 
        end

        % Callback function
        function BTN_AnalyseComponentButtonPushed(app, event)
            app.activeElement.stages(app.stageId).displayStage(app,'impact');
        end

        % Button pushed function: BTN_Edit
        function BTN_EditButtonPushed(app, event)
            app.processesApp = ProcessEditor(app);
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            savedModel = app.model;
            save('lcmSave','savedModel');
        end

        % Menu selected function: openMenu
        function openMenuSelected(app, event)
            load('lcmSave','savedModel');
            app.model = savedModel;
            app.CB_ShowImportedStructureValueChanged();
        end

        % Button pushed function: BTN_Close
        function BTN_CloseButtonPushed(app, event)
            app.closeElement();
        end

        % Button pushed function: BTN_updateComponent
        function BTN_updateComponentButtonPushed(app, event)
            h = waitbar(0.2,['updating model ...']);
            componentId = app.activeElement.id;
            material = app.DD_Material.Value;
            processType = app.DD_Classification.Value;
            app.model.assemblies = app.model.assemblies.updateComponent(app,componentId,material,processType);
            app.activeElement = app.model.assemblies.getElementById(componentId);
            app.classificationType = [];
            app.activeElement.displayComponent(app);
            close(h)
        end

        % Value changed function: DD_Material
        function DD_MaterialValueChanged(app, event)
            value = app.DD_Material.Value;
            if ~isempty(app.classificationType)
                possibleClassificatons = app.classificationList.(app.classificationType){app.materialList == value};
                app.DD_Classification.Items = possibleClassificatons;               
            end
        end

        % Button pushed function: BTN_updateProcess
        function BTN_updateProcessButtonPushed(app, event)
            process = app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId);            
            for a = 1:length(process.alternativeProcesses)
                if process.alternativeProcesses(a).activityName == app.DD_alternativeProcesses.Value                    
                    newProcess = process.alternativeProcesses(a);
                    break;
                end                
            end                                    
           quantity = app.E_Quantity.Value;      
           correction = app.E_Correction.Value;
           componentId = app.activeElement.id;           
           app.model.assemblies = app.model.assemblies.updateProcess(app,process.id,newProcess,quantity,correction);           
           app.activeElement = app.model.assemblies.getElementById(componentId);
           if app.mode == "Operation"
               app.activeElement.stages(app.stageId).operations(app.operationId).displayOperation(app,"impact");               
           elseif app.mode == "Process"
               app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId).displayProcess(app);
           end   
           pro = app.activeElement.stages(app.stageId).operations(app.operationId).processes(app.processId);
           app.DD_availableLocations.Items = pro.locList;
           app.L_functionalunit.Text = string("functional unit: " + string(pro.functionalUnit) + " [" + string(pro.unit) + "]");
               
        end

        % Value changed function: DD_Method
        function DD_MethodValueChanged(app, event)
            app.lciaMethod = app.DD_Method.Value;
            app.listCategories();            
        end

        % Value changed function: DD_Indicator
        function DD_IndicatorValueChanged(app, event)
           app.updateLCIA();
        end

        % Value changed function: DD_Category
        function DD_CategoryValueChanged(app, event)
            app.listIndicators();             
            %app.updateLCIA();
        end

        % Menu selected function: clearprocessengineMenu
        function clearprocessengineMenuSelected(app, event)
            app.processEngine.processes = EcoinventProcess.empty();
            app.processEngine.saveProcesses();
        end

        % Button pushed function: BTN_SensitivityAnalysis
        function BTN_SensitivityAnalysisButtonPushed(app, event)
            w = waitbar(0,['simulating parameter change...']);
            referenceImpact = app.activeElement.generateLCIA(app);
            processParameters = string(fieldnames(app.activeElement.processParameter));
            
            %get only independant parameters
            indiParameters = [];
            for i = 1:length(processParameters)                
               if (app.activeElement.processParameter.(processParameters(i)).dependant == false)                   
                  indiParameters = [indiParameters processParameters(i)] ;
               end               
            end                        
            sensitivity = zeros(length(indiParameters),1);                 
            for i = 1:length(indiParameters)    
                waitbar(i/(length(indiParameters)+3),w,['simulating ' + indiParameters(i) + " change..."]);
                copy = app.activeElement;                
                copy.processParameter.(indiParameters(i)).value = copy.processParameter.(indiParameters(i)).value * 1.1;                
                copy = copy.generateStages(app);
                sensitivity(i) = copy.generateLCIA(app) / referenceImpact * 100 - 100;                                                
            end            
            rateNames = ["recycling","remanufacturing","refurbishment"];
            num = length(indiParameters);
            disp(referenceImpact)
            for i = 1:length(rateNames)  
                 waitbar(num+i/(num+3),w,['simulating ' + rateNames(i) + " change..."]);
                 indiParameters = [indiParameters rateNames(i)] ;    
                 copy = app.activeElement;                       
                 disp(rateNames(i))                 
                 copy.rates.(rateNames(i)) = min(copy.rates.(rateNames(i)) + 0.1 ,1);                                  
                 copy.rates.disposal = 1 - copy.rates.recycling - copy.rates.refurbishment - copy.rates.remanufacturing;
                 copy.rates.material = 1 - copy.rates.recycling - copy.rates.refurbishment - copy.rates.remanufacturing;
                 copy.rates.production = 1 - copy.rates.refurbishment - copy.rates.remanufacturing;
                 copy.rates.assembly = 1 - copy.rates.refurbishment;
                 copy = copy.generateStages(app);
                 disp(copy.generateLCIA(app))
                 sensitivity(num+i) = copy.generateLCIA(app) / referenceImpact * 100 - 100;                                    
            end
                        
            x = [1:10];
            y = x.^2;
            h = barh(app.UIAxes,sensitivity,0.5,'FaceColor','#FFAAAA','EdgeColor','none');                                        
            %title(app.UIAxes,{'','Impact change on 10% parameter variation'},"FontSize",18,"Color", [.3 .3 .35]);
            text(app.UIAxes, h.YData(1)/2 ,2*h.XData(end) - h.XData(end-1),'impact change on 10% parameter variation',"FontSize",18,"Color", [.3 .3 .35],'HorizontalAlignment', 'center','VerticalAlignment','top','FontWeight',"bold");
            text(app.UIAxes, zeros(length(h.XData),1)+max(sensitivity)*0.05, h.XData, indiParameters' + "  " + string(sensitivity) + "%" ,'VerticalAlignment','middle', 'HorizontalAlignment', 'left', 'FontSize', 18, 'Color', [.3 .3 .35], 'FontWeight','normal','Interpreter','none');                        
            close(w)
            app.BTN_Open.Text = "open";
            app.BTN_Open.Enable = false;
            app.BTN_Close.Text = "close analysis";       
            app.BTN_Close.Enable = true;
            app.mode = "Sensitivity";
        end

        % Button pushed function: BTN_Loops
        function BTN_LoopsButtonPushed(app, event)
            loops(app);                        
        end

        % Button pushed function: BTN_Reference
        function BTN_ReferenceButtonPushed(app, event)
            app.referenceModel = app.model;
            app.BTN_Compare.Enable = true;
            f = msgbox("reference set")
        end

        % Button pushed function: BTN_Compare
        function BTN_CompareButtonPushed(app, event)
            if ~isempty(app.referenceModel)
                compareModels(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LifeCycleDesignerUIFigure = uifigure('Visible', 'off');
            app.LifeCycleDesignerUIFigure.Name = 'Life Cycle Designer';
            app.fileMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.fileMenu.Text = 'file';
            app.newMenu = uimenu(app.fileMenu);
            app.newMenu.Text = 'new';
            app.openMenu = uimenu(app.fileMenu);
            app.openMenu.MenuSelectedFcn = createCallbackFcn(app, @openMenuSelected, true);
            app.openMenu.Text = 'open';
            app.importMenu = uimenu(app.fileMenu);
            app.importMenu.MenuSelectedFcn = createCallbackFcn(app, @importMenuSelected, true);
            app.importMenu.Text = 'import';
            app.saveMenu = uimenu(app.fileMenu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = 'save';
            app.mappingMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.mappingMenu.Text = 'mapping';
            app.mappingeditorMenu = uimenu(app.mappingMenu);
            app.mappingeditorMenu.MenuSelectedFcn = createCallbackFcn(app, @mappingeditorMenuSelected, true);
            app.mappingeditorMenu.Text = 'mapping editor';
            app.settingsMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.settingsMenu.Text = 'settings';
            app.indicatormethodMenu = uimenu(app.settingsMenu);
            app.indicatormethodMenu.Text = 'indicator method';
            app.indicatorcategoryMenu = uimenu(app.settingsMenu);
            app.indicatorcategoryMenu.Text = 'indicator category';
            app.lifelinkdirectoryMenu = uimenu(app.settingsMenu);
            app.lifelinkdirectoryMenu.Text = 'life link directory';
            app.loadMatrixMenu = uimenu(app.settingsMenu);
            app.loadMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @loadMatrixMenuSelected, true);
            app.loadMatrixMenu.Text = 'load Matrix';
            app.saveengineMenu = uimenu(app.settingsMenu);
            app.saveengineMenu.MenuSelectedFcn = createCallbackFcn(app, @saveengineMenuSelected, true);
            app.saveengineMenu.Text = 'save engine';
            app.loadengineMenu = uimenu(app.settingsMenu);
            app.loadengineMenu.MenuSelectedFcn = createCallbackFcn(app, @loadengineMenuSelected, true);
            app.loadengineMenu.Text = 'load engine';
            app.debugMenu = uimenu(app.LifeCycleDesignerUIFigure);
            app.debugMenu.Text = 'debug';
            app.exportappMenu = uimenu(app.debugMenu);
            app.exportappMenu.MenuSelectedFcn = createCallbackFcn(app, @exportappMenuSelected, true);
            app.exportappMenu.Text = 'export app';
            app.clearprocessengineMenu = uimenu(app.debugMenu);
            app.clearprocessengineMenu.MenuSelectedFcn = createCallbackFcn(app, @clearprocessengineMenuSelected, true);
            app.clearprocessengineMenu.Text = 'clear process engine';
            app.Btn_LifeLink = uibutton(app.LifeCycleDesignerUIFigure, 'state');
            app.Btn_LifeLink.Text = 'life link';
            app.CB_ShowFeatures = uicheckbox(app.LifeCycleDesignerUIFigure);
            app.CB_ShowFeatures.ValueChangedFcn = createCallbackFcn(app, @CB_ShowFeaturesValueChanged, true);
            app.CB_ShowFeatures.Text = 'show features & CAM';
            app.CB_ShowFeatures.Value = true;
            app.TV_Components = uitree(app.LifeCycleDesignerUIFigure);
            app.TV_Components.SelectionChangedFcn = createCallbackFcn(app, @TV_ComponentsSelectionChanged, true);
            app.P_Component = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Component.Title = ' ';
            app.materialDropDownLabel = uilabel(app.P_Component);
            app.materialDropDownLabel.Text = 'material';
            app.DD_Material = uidropdown(app.P_Component);
            app.DD_Material.Items = {};
            app.DD_Material.ValueChangedFcn = createCallbackFcn(app, @DD_MaterialValueChanged, true);
            app.DD_Material.Value = {};
            app.classificationLabel = uilabel(app.P_Component);
            app.classificationLabel.Text = 'classification';
            app.DD_Classification = uidropdown(app.P_Component);
            app.DD_Classification.Items = {};
            app.DD_Classification.Value = {};
            app.BTN_updateComponent = uibutton(app.P_Component, 'push');
            app.BTN_updateComponent.ButtonPushedFcn = createCallbackFcn(app, @BTN_updateComponentButtonPushed, true);
            app.BTN_updateComponent.Text = 'update component';
            app.CB_IgnoreComponent = uicheckbox(app.P_Component);
            app.CB_IgnoreComponent.Text = 'ignore component';
            app.masskgEditFieldLabel = uilabel(app.P_Component);
            app.masskgEditFieldLabel.Enable = 'off';
            app.masskgEditFieldLabel.Text = 'mass [kg]';
            app.E_Mass = uieditfield(app.P_Component, 'numeric');
            app.E_Mass.Enable = 'off';
            app.volumemLabel = uilabel(app.P_Component);
            app.volumemLabel.Enable = 'off';
            app.volumemLabel.Text = 'volume [m³]';
            app.E_Volume = uieditfield(app.P_Component, 'numeric');
            app.E_Volume.Enable = 'off';
            app.analysisPanel = uipanel(app.LifeCycleDesignerUIFigure);
            app.analysisPanel.Title = ' analysis';
            app.L_Indicator = uilabel(app.analysisPanel);
            app.L_Indicator.Text = 'environmental indicator';
            app.productsystemLabel = uilabel(app.analysisPanel);
            app.productsystemLabel.Text = 'product system';
            app.BTN_Loops = uibutton(app.analysisPanel, 'push');
            app.BTN_Loops.ButtonPushedFcn = createCallbackFcn(app, @BTN_LoopsButtonPushed, true);
            app.BTN_Loops.Enable = 'off';
            app.BTN_Loops.Text = 'adjust loop rates';
            app.methodDropDownLabel = uilabel(app.analysisPanel);
            app.methodDropDownLabel.Text = 'method';
            app.DD_Method = uidropdown(app.analysisPanel);
            app.DD_Method.Items = {};
            app.DD_Method.ValueChangedFcn = createCallbackFcn(app, @DD_MethodValueChanged, true);
            app.DD_Method.Value = {};
            app.indicatorDropDownLabel = uilabel(app.analysisPanel);
            app.indicatorDropDownLabel.Text = 'indicator';
            app.DD_Indicator = uidropdown(app.analysisPanel);
            app.DD_Indicator.Items = {};
            app.DD_Indicator.ValueChangedFcn = createCallbackFcn(app, @DD_IndicatorValueChanged, true);
            app.DD_Indicator.Value = {};
            app.categoryLabel = uilabel(app.analysisPanel);
            app.categoryLabel.Text = 'category';
            app.DD_Category = uidropdown(app.analysisPanel);
            app.DD_Category.Items = {};
            app.DD_Category.ValueChangedFcn = createCallbackFcn(app, @DD_CategoryValueChanged, true);
            app.DD_Category.Value = {};
            app.BTN_SensitivityAnalysis = uibutton(app.analysisPanel, 'push');
            app.BTN_SensitivityAnalysis.ButtonPushedFcn = createCallbackFcn(app, @BTN_SensitivityAnalysisButtonPushed, true);
            app.BTN_SensitivityAnalysis.Enable = 'off';
            app.BTN_SensitivityAnalysis.Text = 'sensitivity analysis';
            app.BTN_Reference = uibutton(app.analysisPanel, 'push');
            app.BTN_Reference.ButtonPushedFcn = createCallbackFcn(app, @BTN_ReferenceButtonPushed, true);
            app.BTN_Reference.Text = 'set reference';
            app.BTN_Compare = uibutton(app.analysisPanel, 'push');
            app.BTN_Compare.ButtonPushedFcn = createCallbackFcn(app, @BTN_CompareButtonPushed, true);
            app.BTN_Compare.Enable = 'off';
            app.BTN_Compare.Text = 'compare to ref';
            app.UIAxes = uiaxes(app.LifeCycleDesignerUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = '';
            app.P_Settings = uipanel(app.LifeCycleDesignerUIFigure);
            app.P_Settings.Title = ' ';
            app.BTN_Open = uibutton(app.P_Settings, 'push');
            app.BTN_Open.ButtonPushedFcn = createCallbackFcn(app, @BTN_OpenButtonPushed, true);
            app.BTN_Open.Enable = 'off';
            app.BTN_Open.Text = 'open stage';
            app.BTN_Edit = uibutton(app.P_Settings, 'push');
            app.BTN_Edit.ButtonPushedFcn = createCallbackFcn(app, @BTN_EditButtonPushed, true);
            app.BTN_Edit.Visible = 'off';
            app.BTN_Edit.Text = 'replace process';
            app.BTN_Add = uibutton(app.P_Settings, 'push');
            app.BTN_Add.Visible = 'off';
            app.BTN_Add.Text = 'add process';
            app.BTN_Close = uibutton(app.P_Settings, 'push');
            app.BTN_Close.ButtonPushedFcn = createCallbackFcn(app, @BTN_CloseButtonPushed, true);
            app.BTN_Close.Enable = 'off';
            app.BTN_Close.Text = 'close';
            app.E_Quantity = uieditfield(app.P_Settings, 'numeric');
            app.L_functionalunit = uilabel(app.P_Settings);
            app.L_functionalunit.Text = 'functional unit';
            app.L_availableProcesses = uilabel(app.P_Settings);
            app.L_availableProcesses.Text = 'available Processes';
            app.DD_alternativeProcesses = uidropdown(app.P_Settings);
            app.DD_alternativeProcesses.Items = {};
            app.DD_alternativeProcesses.Value = {};
            app.BTN_updateProcess = uibutton(app.P_Settings, 'push');
            app.BTN_updateProcess.ButtonPushedFcn = createCallbackFcn(app, @BTN_updateProcessButtonPushed, true);
            app.BTN_updateProcess.Visible = 'off';
            app.BTN_updateProcess.Text = 'update Process';
            app.E_Correction = uieditfield(app.P_Settings, 'numeric');
            app.L_Correction = uilabel(app.P_Settings);
            app.L_Correction.Text = 'correction factor';
            app.L_availableLocations = uilabel(app.P_Settings);
            app.L_availableLocations.Text = 'available locations';
            app.DD_availableLocations = uidropdown(app.P_Settings);
            app.DD_availableLocations.Items = {};
            app.DD_availableLocations.Value = {};
            app.L_Stage = uilabel(app.LifeCycleDesignerUIFigure);
            app.L_Stage.Text = '';
            app.CB_ShowImportedStructure = uicheckbox(app.LifeCycleDesignerUIFigure);
            app.CB_ShowImportedStructure.ValueChangedFcn = createCallbackFcn(app, @CB_ShowImportedStructureValueChanged, true);
            app.CB_ShowImportedStructure.Text = 'show imported structure';
            % Show the figure after all components are created
            app.LifeCycleDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LifeCycleDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LifeCycleDesignerUIFigure)
        end
    end
end