classdef ProcessEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EcoinventExplorerUIFigure  matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        saveMenu                   matlab.ui.container.Menu
        openMenu                   matlab.ui.container.Menu
        exportLCIMenu              matlab.ui.container.Menu
        EditField                  matlab.ui.control.EditField
        searchforprocessButton     matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        ProcessPanel               matlab.ui.container.Panel
        UITable2                   matlab.ui.control.Table
        UITable2_2                 matlab.ui.control.Table
        TextArea                   matlab.ui.control.TextArea
        TextArea_2                 matlab.ui.control.TextArea
        GeneralCommentLabel        matlab.ui.control.Label
        ActivitiesStartLabel       matlab.ui.control.Label
        IntermediateFlowsLabel     matlab.ui.control.Label
        ElementaryFlowsLabel       matlab.ui.control.Label
        TextArea_3                 matlab.ui.control.TextArea
        ActivitiesEndLabel         matlab.ui.control.Label
        BTN_replace                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        T % lookup table containing filenames
        selectedCell % UI table selection
        startProcess = 'steel milling, small parts'  % search query
        struct % for selected process          
        
        fileNames
        
        mainApp
        ecoProcess EcoinventProcess
    end
    
    methods (Access = private)
        
        function processName = loadProcess(app, fileName)                                                            
                h = waitbar(0,['Load Process...']);                
                tmpStruct = xml2struct("ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + fileName);                                        
                
                if isfield(tmpStruct.ecoSpold, 'activityDataset')
                    app.struct = tmpStruct.ecoSpold.activityDataset;
                else
                    app.struct = tmpStruct.ecoSpold.childActivityDataset;
                end


                processName = string(app.struct.activityDescription.activity.activityName.Text);
                app.ProcessPanel.Title = 'Process: ' + processName;


                % display comment in TextArea
                if isfield(app.struct.activityDescription.activity, 'generalComment')
                    textStruct = app.struct.activityDescription.activity.generalComment.text; 
                    if length(textStruct) > 1
                        app.TextArea.Value = textStruct{1}.Text;
                    else
                        app.TextArea.Value = textStruct.Text;
                    end                 
                end
                                
                
                % display included Activities in TextArea
                if (isfield(app.struct.activityDescription.activity,'includedActivitiesStart'))
                    app.TextArea_2.Value = app.struct.activityDescription.activity.includedActivitiesStart.Text;                    
                end 
                if (isfield(app.struct.activityDescription.activity,'includedActivitiesEnd'))
                    app.TextArea_3.Value = app.struct.activityDescription.activity.includedActivitiesEnd.Text;                   
                end
                
                waitbar(0.2,h,['Load Intermediate Exchanges...']);
                % get exchanges and display in UITable2
                exchanges = app.struct.flowData.intermediateExchange';
                exchangeNames = strings(length(exchanges),1);                
                exchangeAmount = strings(length(exchanges),1);
                exchangeUnit = strings(length(exchanges),1);
                exchangeTypes = strings(length(exchanges),1);
                exchangeGroup = strings(length(exchanges),1);
                exchangeId = strings(length(exchanges),1);
                
                if length(exchanges) == 1                    
                   exchangeNames = {exchanges.name.Text};
                   exchangeAmount = {exchanges.Attributes.amount};
                   exchangeUnit = {exchanges.unitName.Text};
                   exchangeId = {exchanges.Attributes.intermediateExchangeId};
                   if isfield(exchanges,'inputGroup') % input                                               
                       exchangeGroup = ['Input ' exchanges.inputGroup.Text];
                       if exchanges.inputGroup.Text == '5'
                           % get 'By-product classification'
                           indexBpc = 0;
                           for j=1:length(exchanges.classification)
                               if string(exchanges.classification{j}.classificationSystem.Text) == 'By-product classification'
                                   indexBpc = j;
                               end
                               if indexBpc > 0
                                   if string(exchanges.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                                       exchangeTypes = 'From Technosphere | allocatable product';
                                   elseif string(exchanges.classification{indexBpc}.classificationValue.Text) == 'Waste'                                   
                                       exchangeTypes = 'From Technosphere | waste';
                                   elseif string(exchanges.classification{indexBpc}.classificationValue.Text) == 'Recyclable'                                   
                                       exchangeTypes = 'From Technosphere | Recyclable';
                                   end
                               else
                                   exchangeTypes = 'error';
                               end                           
                           end
                       else
                           exchangeTypes = '???';
                       end                       
                   else % output
                       if exchanges.outputGroup.Text == '0'
                           exchangeTypes = 'ReferenceProduct, Output';
                       else
                           exchangeTypes = 'By-Product/Waste, Output';
                       end
                       exchangeGroup = ['Output ' exchanges.outputGroup.Text];
                   end
                   exchangeGroup = {exchangeGroup};
                   exchangeTypes = {exchangeTypes};                   
                   
                else                
                    for i=1:length(exchanges)
                       exchangeNames(i) = exchanges{i}.name.Text;
                       exchangeAmount(i) = exchanges{i}.Attributes.amount;
                       exchangeUnit(i) = exchanges{i}.unitName.Text;                       
                       exchangeId(i) = {exchanges{i}.Attributes.intermediateExchangeId};
                       if isfield(exchanges{i},'inputGroup') % input                                               
                           exchangeGroup(i) = ['Input ' exchanges{i}.inputGroup.Text];
                           if exchanges{i}.inputGroup.Text == '5'
                               % get 'By-product classification'
                               indexBpc = 0;
                               for j=1:length(exchanges{i}.classification)
                                   if string(exchanges{i}.classification{j}.classificationSystem.Text) == 'By-product classification'
                                       indexBpc = j;
                                   end
                                   if indexBpc > 0
                                       if string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                                           exchangeTypes(i) = 'From Technosphere | allocatable product';
                                       elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Waste'                                   
                                           exchangeTypes(i) = 'From Technosphere | waste';
                                       elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Recyclable'                                   
                                           exchangeTypes(i) = 'From Technosphere | Recyclable';
                                       end
                                   else
                                       exchangeTypes(i) = 'error';
                                   end                           
                               end
                           else
                               exchangeTypes(i) = '???';
                           end                       
                       else % output
                           if exchanges{i}.outputGroup.Text == '0'
                               exchangeTypes(i) = 'ReferenceProduct, Output';
                           else
                               exchangeTypes(i) = 'By-Product/Waste, Output';
                           end
                           exchangeGroup(i) = ['Output ' exchanges{i}.outputGroup.Text];
                       end                               
                    end
                end
                %app.UITable2.ColumnName = [{'ID'}, {'FlowName'}, {'Amount'}, {'Unit'}, {'FlowType'},{'Group'}];
                app.UITable2.ColumnName = [{'FlowName'}, {'Amount'}, {'Unit'}, {'FlowType'},{'Group'}];
                app.UITable2_2.ColumnName = app.UITable2.ColumnName;
                %FlowTable = table(exchangeId,exchangeNames,exchangeAmount,exchangeUnit,exchangeTypes,exchangeGroup);
                FlowTable = table(exchangeNames,exchangeAmount,exchangeUnit,exchangeTypes,exchangeGroup);
                %FlowTable = sortrows(FlowTable,4,"ascend"); 
                app.UITable2.Data = FlowTable;                
                
                waitbar(0.6,h,['Load Elementary Flows...']);
                % get elementary flows and display in UITable2_2
                if isfield(app.struct.flowData, 'elementaryExchange')
                    eflows = app.struct.flowData.elementaryExchange';
                    eflowId = strings(length(eflows ),1);
                    eflowNames = strings(length(eflows ),1);                
                    eflowAmount = strings(length(eflows ),1);
                    eflowUnit = strings(length(eflows ),1);
                    eflowType = strings(length(eflows ),1);
                    eflowGroup = strings(length(eflows ),1);
                    
                    if length(eflows) > 1
                        for i = 1:length(eflows)
                            eflowId(i) = {string(eflows{i}.Attributes.elementaryExchangeId)};
                            eflowNames(i) = string(eflows{i}.name.Text);                                    
                            eflowAmount(i) = string(eflows{i}.Attributes.amount);
                            eflowUnit(i) = string(eflows{i}.unitName.Text);
                            subcomp = eflows{i}.compartment.compartment.Text;
                            if isfield(eflows{i}, 'inputGroup')
                                eflowType(i) = ['From Environment | ' subcomp];
                                eflowGroup(i) = ['Input ' eflows{i}.inputGroup.Text];
                            else                                
                                eflowType(i) = ['To Environment | ' subcomp];
                                eflowGroup(i) = ['Output ' eflows{i}.outputGroup.Text];
                            end
                            
                        end
                    else
                        %eflowId = {string(eflows.Attributes.elementaryExchangeId)};
                        eflowNames = {string(eflows.name.Text)};
                        eflowAmount = {string(eflows.Attributes.amount)};
                        eflowUnit = {string(eflows.unitName.Text)};                        
                        subcomp = eflows.compartment.compartment.Text;
                        if isfield(eflows, 'inputGroup')
                                eflowType = {['From Environment | ' subcomp]};
                                eflowGroup = {['Input' eflows.inputGroup.Text]};
                        else                                                                
                                eflowType = {['To Environment | ' subcomp]};
                                eflowGroup = {['Output' eflows.outputGroup.Text]};                                
                        end
                        
                    end                                         
                    
                else
                   %eflowId = cell(0);
                   eflowNames = cell(0);
                   eflowAmount = cell(0);
                   eflowUnit = cell(0);
                   eflowType = cell(0);
                   eflowGroup = cell(0);
                end
                
                waitbar(0.9,h,['Display Flows...']);
                %FlowTable = table(eflowId,eflowNames,eflowAmount,eflowUnit,eflowType,eflowGroup);
                FlowTable = table(eflowNames,eflowAmount,eflowUnit,eflowType,eflowGroup);
                %FlowTable = sortrows(FlowTable,1,"ascend"); 
                app.UITable2_2.Data = FlowTable;
                app.visualize();
                close(h)                   
        end
        
        
        function visualize(app)
            activityName = string(app.UITable.Data{app.selectedCell(1),1});
            activityLoc = string(app.UITable.Data{app.selectedCell(1),2});
            activityType ='Type';            
            proc = EcoinventProcess(app.mainApp,activityName,activityLoc,activityType);            
            proc.displayProcess(app.mainApp,app.UIAxes);            
            app.ecoProcess = proc;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            activityName = mainApp.activeElement.stages(mainApp.stageId).processes(mainApp.selectionId).activityName;               
            mainApp.selectionId
            app.T = readtable('ecoinvent 3.6_cut-off_ecoSpold02\FilenameToActivtiyLookup.csv','Format','%s %s %s %s');                        
            app.mainApp = mainApp;            
            if activityName ~= "empty"
                app.startProcess = activityName;
                app.EditField.Value = app.startProcess;
                app.searchforprocessButtonPushed(); % automatically click load button
            else
                app.EditField.Value = "";
            end
            
                   
        end

        % Button pushed function: searchforprocessButton
        function searchforprocessButtonPushed(app, event)
            searchString = app.EditField.Value;            
            Occ = app.T(find(contains(app.T.ActivityName,searchString)),:);
            app.fileNames = Occ.Filename;
            Occ.Filename = [];
            app.UITable.ColumnName = Occ.Properties.VariableNames;                                               
            app.UITable.Data = Occ;            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
           app.selectedCell = event.Indices;
           if ~isempty(app.selectedCell)                                                               
                index = event.Indices(1);
                filename = app.fileNames{index};                            
                app.loadProcess(filename);                    
           end                     
           
        end

        % Menu selected function: saveMenu
        function saveMenuSelected(app, event)
            [file,path] = uiputfile('*.mat');
            drawnow;
            figure(app.EcoinventExplorerUIFigure)
            if ~isempty(file)
                lci_table_b = app.lciTable;
                lci_b = app.lci;
                G_b = app.G;
                nodes_b = app.Tree.Children;
                time = app.processTime;       
                nCount = app.nodeCount;
                save(fullfile(path,file),'lci_b','lci_table_b','G_b','nodes_b','time','nCount');
                drawnow;
                figure(app.EcoinventExplorerUIFigure)
                msgbox('saved to ' + string(file));
            end
        end

        % Menu selected function: openMenu
        function openMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            drawnow;
            figure(app.EcoinventExplorerUIFigure)
            if ~isempty(file)            
                h = waitbar(0,['Load Process...']);
                delete(app.Tree.Children);             
                load(fullfile(path,file),'lci_b','lci_table_b','G_b','nodes_b','time','nCount');
                app.nodeCount = nCount;
                app.nodeCount = nCount;
                waitbar(50,h,['Integrate Data...'])
                nodes_b.Parent=app.Tree;            
                app.processTime = time;
                app.lciTable = lci_table_b;
                expand(app.Tree,'all');
                app.G = G_b;
                app.lci = lci_b;
                close(h)
                drawnow;
                figure(app.EcoinventExplorerUIFigure)
                msgbox('opened ' + string(file));   
            end
        end

        % Button pushed function: BTN_replace
        function BTN_replacePushed(app, event)
            %app.mainApp.activeElement.stages(app.mainApp.stageId).processes(app.mainApp.selectionId) = app.ecoProcess;
            app.mainApp.activeElement.stages(app.mainApp.stageId) = app.mainApp.activeElement.stages(app.mainApp.stageId).addProcess(app.ecoProcess,app.mainApp.selectionId);
            app.mainApp.model = app.mainApp.model.updateElement(app.mainApp.activeElement);
            
            app.mainApp.activeElement.stages(app.mainApp.stageId).displayStage(app.mainApp,'impact')  ;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EcoinventExplorerUIFigure = uifigure('Visible', 'off');
            app.EcoinventExplorerUIFigure.Name = 'Ecoinvent Explorer';
            app.FileMenu = uimenu(app.EcoinventExplorerUIFigure);
            app.FileMenu.Text = 'File';
            app.saveMenu = uimenu(app.FileMenu);
            app.saveMenu.MenuSelectedFcn = createCallbackFcn(app, @saveMenuSelected, true);
            app.saveMenu.Text = 'save';
            app.openMenu = uimenu(app.FileMenu);
            app.openMenu.MenuSelectedFcn = createCallbackFcn(app, @openMenuSelected, true);
            app.openMenu.Text = 'open';
            app.exportLCIMenu = uimenu(app.FileMenu);
            app.exportLCIMenu.Text = 'export LCI';
            app.EditField = uieditfield(app.EcoinventExplorerUIFigure, 'text');
            app.searchforprocessButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.searchforprocessButton.ButtonPushedFcn = createCallbackFcn(app, @searchforprocessButtonPushed, true);
            app.searchforprocessButton.Text = 'search for process';
            app.UITable = uitable(app.EcoinventExplorerUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ProcessPanel = uipanel(app.EcoinventExplorerUIFigure);
            app.ProcessPanel.Title = 'Process';
            app.UITable2 = uitable(app.ProcessPanel);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = true;
            app.UITable2_2 = uitable(app.ProcessPanel);
            app.UITable2_2.ColumnName = {''};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnSortable = true;
            app.UITable2_2.ColumnEditable = true;
            app.TextArea = uitextarea(app.ProcessPanel);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.ProcessPanel);
            app.TextArea_2.Editable = 'off';
            app.GeneralCommentLabel = uilabel(app.ProcessPanel);
            app.GeneralCommentLabel.Text = 'General Comment';
            app.ActivitiesStartLabel = uilabel(app.ProcessPanel);
            app.ActivitiesStartLabel.Text = 'Activities Start';
            app.IntermediateFlowsLabel = uilabel(app.ProcessPanel);
            app.IntermediateFlowsLabel.Text = 'Intermediate Flows';
            app.ElementaryFlowsLabel = uilabel(app.ProcessPanel);
            app.ElementaryFlowsLabel.Text = 'Elementary Flows';
            app.TextArea_3 = uitextarea(app.ProcessPanel);
            app.TextArea_3.Editable = 'off';
            app.ActivitiesEndLabel = uilabel(app.ProcessPanel);
            app.ActivitiesEndLabel.Text = 'Activities End';
            app.BTN_replace = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.BTN_replace.ButtonPushedFcn = createCallbackFcn(app, @BTN_replacePushed, true);
            app.BTN_replace.Text = 'replace process';
            app.UIAxes = uiaxes(app.EcoinventExplorerUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = 0;
            app.UIAxes.YTickLabel = '';
            % Show the figure after all components are created
            app.EcoinventExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EcoinventExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EcoinventExplorerUIFigure)
        end
    end
end