classdef VisualizeXml < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        loadxmlButton  matlab.ui.control.Button
        openxmlButton  matlab.ui.control.Button
        Tree           matlab.ui.container.Tree
        EditField      matlab.ui.control.EditField
        UITable        matlab.ui.control.Table
    end

    
    properties (Access = private)
        fileName = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml'; %Xml Dir
    end
    
    methods (Access = private)        
            
        function addNode(app,root,struct)            
            if isfield(struct,"solid")
                for i = 1: length(struct.solid)
                    if length(struct.solid) == 1
                        solid = uitreenode(root,'Text',struct.solid(i).Attributes.name,'NodeData',struct.solid(i).Attributes,"icon","res\solid.png");    
                    else
                        solid = uitreenode(root,'Text',struct.solid{i}.Attributes.name,'NodeData',struct.solid{i}.Attributes,"icon","res\solid.png");    
                    end
                end
            end
            if isfield(struct,"part")
                for i = 1: length(struct.part)
                    if length(struct.part) == 1
                        part = uitreenode(root,'Text',struct.part(i).Attributes.name,'NodeData',struct.part(i).Attributes,"icon","res\part.png");    
                        app.addNode(part,struct.part(i));
                    else
                        part = uitreenode(root,'Text',struct.part{i}.Attributes.name,'NodeData',struct.part{i}.Attributes,"icon","res\part.png");    
                        app.addNode(part,struct.part{i});
                    end
                    
                end    
            end
            if isfield(struct,"CAM")
                cam = uitreenode(root,'Text','CAM','NodeData',[]);    
                app.addNode(cam,struct.CAM);
%                 for i = 1: length(struct.CAM)
%                     if length(struct.CAM) == 1
%                         cam = uitreenode(root,'Text','CAM','NodeData',[]);    
%                         app.addNode(cam,struct.CAM);
%                     else
%                         cam = uitreenode(root,'Text','CAM','NodeData',[]);    
%                         app.addNode(cam,struct.CAM{i});
%                     end                    
%                 end    
            end
            if isfield(struct,"Setup")
                for i = 1: length(struct.Setup)
                    if length(struct.Setup) == 1
                        setup = uitreenode(root,'Text',struct.Setup(i).Attributes.name,'NodeData',struct.Setup(i).Attributes);    
                        app.addNode(setup,struct.Setup);
                    else
                        setup = uitreenode(root,'Text',struct.Setup{i}.Attributes.name,'NodeData',struct.Setup{i}.Attributes);    
                        app.addNode(setup,struct.Setup{i});
                    end
                    
                end    
            end
            if isfield(struct,"Operation")
                for i = 1: length(struct.Operation)
                    if length(struct.Operation) == 1
                        op = uitreenode(root,'Text',struct.Operation(i).Attributes.name,'NodeData',struct.Operation(i).Attributes);    
                        app.addNode(op,struct.Operation(i));
                    else
                        op = uitreenode(root,'Text',struct.Operation{i}.Attributes.name,'NodeData',struct.Operation{i}.Attributes);    
                        app.addNode(op,struct.Operation{i});
                    end
                    
                end    
            end
            if isfield(struct,"Model")
                for i = 1: length(struct.Model)
                    if length(struct.Model) == 1
                        model = uitreenode(root,'Text',struct.Model(i).Attributes.name,'NodeData',struct.Model(i).Attributes,"icon","res\part.png");                            
                    else
                        model = uitreenode(root,'Text',struct.Model{i}.Attributes.name,'NodeData',struct.Model{i}.Attributes,"icon","res\part.png"); 
                    end
                    
                end    
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = app.fileName;
        end

        % Button pushed function: loadxmlButton
        function loadxmlButtonPushed(app, event)
            path = 'C:\Users\Z420\Desktop\exchange_folder\F-BOM.xml';
            c=xml2struct(path); 
            
            app.Tree.Children.delete;
            
            assembly = uitreenode(app.Tree,'Text',c.assembly.Attributes.name,'NodeData',c.assembly.Attributes, "Icon", "res\asm.png");
            app.addNode(assembly,c.assembly);                       
            
            
            expand(app.Tree,'all');
                        
            
        end

        % Button pushed function: openxmlButton
        function openxmlButtonPushed(app, event)
        title = 'Open XML File';
        filter = '../*.xml';       
        [file, path] = uigetfile(filter,title,app.fileName);
        if (file ~= 0)                    
            app.fileName = [path file];
            app.EditField.Value = app.fileName;
        end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            %display(selectedNodes.NodeData);
            %app.UITable.Data = [fieldnames(selectedNodes.NodeData) struct2cell(selectedNodes.NodeData)];
            if isempty(selectedNodes.NodeData)
                app.UITable.ColumnName = [];
                app.UITable.Data = [];
            else
                app.UITable.RowName = fieldnames(selectedNodes.NodeData)';
                app.UITable.Data = struct2cell(selectedNodes.NodeData);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.loadxmlButton = uibutton(app.UIFigure, 'push');
            app.loadxmlButton.ButtonPushedFcn = createCallbackFcn(app, @loadxmlButtonPushed, true);
            app.loadxmlButton.Text = 'load xml';
            app.openxmlButton = uibutton(app.UIFigure, 'push');
            app.openxmlButton.ButtonPushedFcn = createCallbackFcn(app, @openxmlButtonPushed, true);
            app.openxmlButton.Text = 'open xml';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeXml

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end