classdef openEcoSpold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EcoinventExplorerUIFigure  matlab.ui.Figure
        EditField                  matlab.ui.control.EditField
        searchforprocessButton     matlab.ui.control.Button
        Tree                       matlab.ui.container.Tree
        UITable                    matlab.ui.control.Table
        ProcessPanel               matlab.ui.container.Panel
        UITable2                   matlab.ui.control.Table
        UITable2_2                 matlab.ui.control.Table
        TextArea                   matlab.ui.control.TextArea
        TextArea_2                 matlab.ui.control.TextArea
        GeneralCommentLabel        matlab.ui.control.Label
        ActivitiesStartLabel       matlab.ui.control.Label
        IntermediateFlowsLabel     matlab.ui.control.Label
        ElementaryFlowsLabel       matlab.ui.control.Label
        TextArea_3                 matlab.ui.control.TextArea
        ActivitiesEndLabel         matlab.ui.control.Label
        FlowPanel                  matlab.ui.container.Panel
        UITable3                   matlab.ui.control.Table
        PropertiesLabel            matlab.ui.control.Label
        UITable4                   matlab.ui.control.Table
        ProviderLabel              matlab.ui.control.Label
        setProviderButton          matlab.ui.control.Button
        Out                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        T % lookup table containing filenames
        selectedCell % UI table selection
        startProcess = 'steel milling, small parts'  % Description
        struct                 
        providerSelection  % Description
        activeNode
    end
    
    methods (Access = private)
        
        function processName = loadProcess(app, fileName)                                            
                
                app.struct = xml2struct("D:\studium\TUM\BA\CE\data_handling\matlab_lca\matlab_zolca\db\Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + fileName);
                
                processName = string(app.struct.ecoSpold.childActivityDataset.activityDescription.activity.activityName.Text);
                app.ProcessPanel.Title = 'Process: ' + processName;


                % display comment in TextArea
                textStruct = app.struct.ecoSpold.childActivityDataset.activityDescription.activity.generalComment.text;
                if length(textStruct) > 1
                    app.TextArea.Value = textStruct{1}.Text;
                else
                    app.TextArea.Value = textStruct.Text;
                end 
                
                % display included Activities in TextArea
                if (isfield('includedActivitesStart', app.struct.ecoSpold.childActivityDataset.activityDescription.activity ))
                    app.TextArea_2.Value = app.struct.ecoSpold.childActivityDataset.activityDescription.activity.includedActivitiesStart.Text;
                end 
                if (isfield('includedActivitiesEnd', app.struct.ecoSpold.childActivityDataset.activityDescription.activity ))
                    app.TextArea_3.Value = app.struct.ecoSpold.childActivityDataset.activityDescription.activity.includedActivitiesEnd.Text;
                end
                
                
                % get exchanges and display in UITable2
                exchanges = app.struct.ecoSpold.childActivityDataset.flowData.intermediateExchange';
                exchangeNames = strings(length(exchanges),1);                
                exchangeAmount = strings(length(exchanges),1);
                exchangeUnit = strings(length(exchanges),1);
                exchangeTypes = strings(length(exchanges),1);
                exchangeGroup = strings(length(exchanges),1);
                for i=1:length(exchanges)
                   exchangeNames(i) = exchanges{i}.name.Text;
                   exchangeAmount(i) = exchanges{i}.Attributes.amount;
                   exchangeUnit(i) = exchanges{i}.unitName.Text;
                   exchangeUnit(i) = exchanges{i}.unitName.Text;
                   if isfield(exchanges{i},'inputGroup') % input                                               
                       exchangeGroup(i) = ['Input ' exchanges{i}.inputGroup.Text];
                       if exchanges{i}.inputGroup.Text == '5'
                           % get 'By-product classification'
                           indexBpc = 0;
                           for j=1:length(exchanges{i}.classification)
                               if string(exchanges{i}.classification{j}.classificationSystem.Text) == 'By-product classification'
                                   indexBpc = j;
                               end
                               if indexBpc > 0
                                   if string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'allocatable product'
                                       exchangeTypes(i) = 'From Technosphere | allocatable product';
                                   elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Waste'                                   
                                       exchangeTypes(i) = 'From Technosphere | waste';
                                   elseif string(exchanges{i}.classification{indexBpc}.classificationValue.Text) == 'Recyclable'                                   
                                       exchangeTypes(i) = 'From Technosphere | Recyclable';
                                   end
                               else
                                   exchangeTypes(i) = 'error';
                               end                           
                           end
                       else
                           exchangeTypes(i) = '???';
                       end                       
                   else % output
                       if exchanges{i}.outputGroup.Text == '0'
                           exchangeTypes(i) = 'ReferenceProduct, Output';
                       else
                           exchangeTypes(i) = 'By-Product/Waste, Output';
                       end
                       exchangeGroup(i) = ['Output ' exchanges{i}.outputGroup.Text];
                   end
                                
                end
                app.UITable2.ColumnName = [{'FlowName'}, {'Amount'}, {'Unit'}, {'FlowType'},{'Group'}];
                app.UITable2_2.ColumnName = app.UITable2.ColumnName;
                FlowTable = table(exchangeNames,exchangeAmount,exchangeUnit,exchangeTypes,exchangeGroup);
                FlowTable = sortrows(FlowTable,4,"ascend"); 
                app.UITable2.Data = FlowTable;                
                
                % get elementary flows and display in UITable2_2
                if isfield(app.struct.ecoSpold.childActivityDataset.flowData, 'elementaryExchange')
                    eflows = app.struct.ecoSpold.childActivityDataset.flowData.elementaryExchange';
                    eflowNames = strings(length(eflows ),1);                
                    eflowAmount = strings(length(eflows ),1);
                    eflowUnit = strings(length(eflows ),1);
                    eflowType = strings(length(eflows ),1);
                    eflowGroup = strings(length(eflows ),1);
                    
                    if length(eflows) > 1
                        for i = 1:length(eflows)
                            eflowNames(i) = string(eflows{i}.name.Text);                                    
                            eflowAmount(i) = string(eflows{i}.Attributes.amount);
                            eflowUnit(i) = string(eflows{i}.unitName.Text);
                            subcomp = eflows{i}.compartment.compartment.Text;
                            if isfield(eflows{i}, 'inputGroup')
                                eflowType(i) = ['From Environment | ' subcomp];
                                eflowGroup(i) = ['Input ' eflows{i}.inputGroup.Text];
                            else                                
                                eflowType(i) = ['To Environment | ' subcomp];
                                eflowGroup(i) = ['Output ' eflows{i}.outputGroup.Text];
                            end
                            
                        end
                    else
                        eflowNames = string(eflows.name.Text);                                    
                        eflowAmount = string(eflows.Attributes.amount);
                        eflowUnit = string(eflows.unitName.Text);
                        subcomp = eflows.compartment.compartment.Text;
                        if isfield(eflows, 'inputGroup')
                                eflowType = ['From Environment | ' subcomp];
                                eflowGroup = ['Input' eflows.inputGroup.Text];
                        else                                                                
                                eflowType = ['To Environment | ' subcomp];
                                eflowGroup = ['Output' eflows.outputGroup.Text];                                
                        end
                        
                    end                                         
                    FlowTable = table([eflowNames,eflowAmount,eflowUnit,eflowType,eflowGroup]);
                    FlowTable = sortrows(FlowTable,1,"ascend"); 
                    app.UITable2_2.Data = FlowTable;                                                    
                end
                            
        end
        
        function getFlowByName(app, name)
            index = 0;
            exFlow = app.struct.ecoSpold.childActivityDataset.flowData.intermediateExchange';            
            app.FlowPanel.Title = 'Flow: ' + name;                          
            
            for i=1:length(exFlow)                
                if string(exFlow{i}.name.Text) == name
                    index = i;
                    break
                end
            end             
            if index > 0
                % get Provider
                provId = exFlow{index}.Attributes.intermediateExchangeId;
                providerTable = app.T(find(contains(app.T.Filename,provId)),1:2);                
                app.UITable4.ColumnName = providerTable.Properties.VariableNames;
                app.UITable4.Data = providerTable;


                FlowNames = strings(0);
                if isfield(exFlow{index}, 'property')
                    props = exFlow{index}.property;                
                    FlowNames = strings(length(props),1);
                    if length(props) == 1
                        FlowNames = props.name.Text;
                    else
                    for e=1:length(props)
                        FlowNames(e) = props{e}.name.Text;
                    end                    
                    end
                else                    
                    app.Out.Text = string(exFlow{index}.name.Text) + ' has no props';
                end 
                exFlowTable = table(FlowNames);
                app.UITable3.Data = exFlowTable;                    
            else
                
            end            
        end
        
        function addProvider(app,id, node)
            provStruct = xml2struct("D:\studium\TUM\BA\CE\data_handling\matlab_lca\matlab_zolca\db\Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\datasets\" + id);                                                          
            app.Out.Text = provStruct.childActivityDataset.activityDescription.activity.activityName.Text;
            provider = uitreenode(node,'Text',processName,'NodeData',app.struct, "Icon", "res\asm.png");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.T = readtable('D:\studium\TUM\BA\CE\data_handling\matlab_lca\matlab_zolca\db\Ecoinvent - cut-off system model\ecoinvent 3.6_cut-off_ecoSpold02\FilenameToActivtiyLookup.csv','Format','%s %s %s %s');            
            app.UITable.ColumnName = app.T.Properties.VariableNames;
            
            app.EditField.Value = app.startProcess;
            app.searchforprocessButtonPushed(); % automatically click load button
            
                        
        end

        % Button pushed function: searchforprocessButton
        function searchforprocessButtonPushed(app, event)
            searchString = app.EditField.Value;            
            Occ = app.T(find(contains(app.T.ActivityName,searchString)),:);
            app.UITable.Data = Occ;            
            
        end

        % Callback function
        function loadprocessButtonPushed(app, event)
            if ~isempty(app.selectedCell)                                                               
                %Add selected process name to panel                                
                filename = string(app.UITable.Data{app.selectedCell(1),1}); % get filename for selected cell in UITable            
                app.loadProcess(filename)
            end 
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
           app.selectedCell = event.Indices;
           if ~isempty(app.selectedCell)                                                               
                %Add selected process name to panel
                filename = string(app.UITable.Data{app.selectedCell(1),1}); % get filename for selected cell in UITable            
                processName = app.loadProcess(filename)
                app.Tree.Children.delete;            
                mainprocess = uitreenode(app.Tree,'Text',processName,'NodeData',filename, "Icon", "res\part.png");
                app.activeNode = mainprocess;
           end                     
           
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
            indices = event.Indices;
            flowName = app.UITable2.Data{indices(1),1};
            app.getFlowByName(flowName)
        end

        % Button pushed function: setProviderButton
        function setProviderButtonPushed(app, event)
            ProviderName = string(app.UITable4.Data{app.providerSelection(1),2});            
            ProviderFile = string(app.UITable4.Data{app.providerSelection(1),1});
            providerNode = uitreenode(app.activeNode,'Text',ProviderName,'NodeData',ProviderFile, "Icon", "res\asm.png");            
            expand(app.Tree,'all');
            
        end

        % Cell selection callback: UITable4
        function UITable4CellSelection(app, event)
            app.providerSelection = event.Indices;
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.activeNode = selectedNodes;
            if ~isempty(selectedNodes)                
                processFile = string(selectedNodes.NodeData);
                app.loadProcess(processFile)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EcoinventExplorerUIFigure = uifigure('Visible', 'off');
            app.EcoinventExplorerUIFigure.Name = 'Ecoinvent Explorer';
            app.EditField = uieditfield(app.EcoinventExplorerUIFigure, 'text');
            app.searchforprocessButton = uibutton(app.EcoinventExplorerUIFigure, 'push');
            app.searchforprocessButton.ButtonPushedFcn = createCallbackFcn(app, @searchforprocessButtonPushed, true);
            app.searchforprocessButton.Text = 'search for process';
            app.Tree = uitree(app.EcoinventExplorerUIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UITable = uitable(app.EcoinventExplorerUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ProcessPanel = uipanel(app.EcoinventExplorerUIFigure);
            app.ProcessPanel.Title = 'Process';
            app.UITable2 = uitable(app.ProcessPanel);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.UITable2_2 = uitable(app.ProcessPanel);
            app.UITable2_2.ColumnName = {''};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnSortable = true;
            app.TextArea = uitextarea(app.ProcessPanel);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.ProcessPanel);
            app.TextArea_2.Editable = 'off';
            app.GeneralCommentLabel = uilabel(app.ProcessPanel);
            app.GeneralCommentLabel.Text = 'General Comment';
            app.ActivitiesStartLabel = uilabel(app.ProcessPanel);
            app.ActivitiesStartLabel.Text = 'Activities Start';
            app.IntermediateFlowsLabel = uilabel(app.ProcessPanel);
            app.IntermediateFlowsLabel.Text = 'Intermediate Flows';
            app.ElementaryFlowsLabel = uilabel(app.ProcessPanel);
            app.ElementaryFlowsLabel.Text = 'Elementary Flows';
            app.TextArea_3 = uitextarea(app.ProcessPanel);
            app.TextArea_3.Editable = 'off';
            app.ActivitiesEndLabel = uilabel(app.ProcessPanel);
            app.ActivitiesEndLabel.Text = 'Activities End';
            app.FlowPanel = uipanel(app.ProcessPanel);
            app.FlowPanel.Title = 'Flow';
            app.UITable3 = uitable(app.FlowPanel);
            app.UITable3.ColumnName = {''};
            app.UITable3.RowName = {};
            app.PropertiesLabel = uilabel(app.FlowPanel);
            app.PropertiesLabel.Text = 'Properties';
            app.UITable4 = uitable(app.FlowPanel);
            app.UITable4.ColumnName = {''};
            app.UITable4.RowName = {};
            app.UITable4.CellSelectionCallback = createCallbackFcn(app, @UITable4CellSelection, true);
            app.ProviderLabel = uilabel(app.FlowPanel);
            app.ProviderLabel.Text = 'Provider';
            app.setProviderButton = uibutton(app.FlowPanel, 'push');
            app.setProviderButton.ButtonPushedFcn = createCallbackFcn(app, @setProviderButtonPushed, true);
            app.setProviderButton.Text = 'setProvider';
            app.Out = uilabel(app.EcoinventExplorerUIFigure);
            app.Out.Text = '';
            % Show the figure after all components are created
            app.EcoinventExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = openEcoSpold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EcoinventExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EcoinventExplorerUIFigure)
        end
    end
end